classdef Frequency_mode_original < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        k_dEditField                   matlab.ui.control.NumericEditField
        k_dEditFieldLabel              matlab.ui.control.Label
        k_lEditField                   matlab.ui.control.NumericEditField
        k_lEditFieldLabel              matlab.ui.control.Label
        k_tEditField                   matlab.ui.control.NumericEditField
        k_tEditFieldLabel              matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        MEditField                     matlab.ui.control.NumericEditField
        MEditFieldLabel                matlab.ui.control.Label
        x4ElementHeadRFCoilfor7TLabel  matlab.ui.control.Label
        SelecttheModesLabel            matlab.ui.control.Label
        CreditShadeebHossainLabel      matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        FrequencyMHzEditField          matlab.ui.control.NumericEditField
        FrequencyMHzEditFieldLabel     matlab.ui.control.Label
        TauDropDown                    matlab.ui.control.DropDown
        TauDropDownLabel               matlab.ui.control.Label
        SigmaDropDown                  matlab.ui.control.DropDown
        SigmaDropDownLabel             matlab.ui.control.Label
        CpFEditField                   matlab.ui.control.NumericEditField
        CpFEditFieldLabel              matlab.ui.control.Label
        LnHEditField                   matlab.ui.control.NumericEditField
        LnHEditFieldLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
L_1=app.LnHEditField.Value;
L=L_1*1e-9;
C_1=app.CpFEditField.Value;
C_p=C_1*1e-12;
%Cp=1.8e-12;

%%%% Variables for A
k_l=app.k_lEditField.Value;
M=app.MEditField.Value;
%sigma=1:M
sigma=app.SigmaDropDown.Value;
a=((pi*sigma)./(M+1));
a_1=cos(a)
A=(1-(2*k_l*a_1));

%%%% Variables for B
k_t=app.k_tEditField.Value;
N=app.NEditField.Value;
tau=app.TauDropDown.Value;
%tau=1;
b=(2*pi.*tau)./N;
b_1=cos(b);
B=2.*k_t.*b_1;

%%% Variable for C
k_d=app.k_dEditField.Value;
%k_d=L/(sqrt(L*L));
%k_d=0;
c_1=b_1*a_1;
C=4.*k_d.*c_1;
D=A-B-C;
w=(sqrt(L.*C_p.*D));
w_1=1./w;
%C_p=(Cp*4)./1e-12;
w_f=(w_1./1e6)./(2.*pi)
app.FrequencyMHzEditField.Value=w_f;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LnHEditFieldLabel = uilabel(app.UIFigure);
            app.LnHEditFieldLabel.Text = 'L(nH)';
            app.LnHEditField = uieditfield(app.UIFigure, 'numeric');
            app.CpFEditFieldLabel = uilabel(app.UIFigure);
            app.CpFEditFieldLabel.Text = 'C(pF)';
            app.CpFEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaDropDownLabel = uilabel(app.UIFigure);
            app.SigmaDropDownLabel.Text = 'Sigma ';
            app.SigmaDropDown = uidropdown(app.UIFigure);
            app.SigmaDropDown.Items = {'1', '2', '3', '4', '5'};
            app.SigmaDropDown.Value = '1';
            app.TauDropDownLabel = uilabel(app.UIFigure);
            app.TauDropDownLabel.Text = 'Tau';
            app.TauDropDown = uidropdown(app.UIFigure);
            app.TauDropDown.Items = {'0', '1', '2', '3', '4', '5'};
            app.TauDropDown.Value = '1';
            app.FrequencyMHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CreditShadeebHossainLabel = uilabel(app.UIFigure);
            app.CreditShadeebHossainLabel.Text = 'Credit : Shadeeb Hossain ';
            app.SelecttheModesLabel = uilabel(app.UIFigure);
            app.SelecttheModesLabel.Text = 'Select the Modes ';
            app.x4ElementHeadRFCoilfor7TLabel = uilabel(app.UIFigure);
            app.x4ElementHeadRFCoilfor7TLabel.Text = '8 x 4 Element Head RF Coil for 7 T ';
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.k_tEditFieldLabel = uilabel(app.UIFigure);
            app.k_tEditFieldLabel.Text = 'k_t';
            app.k_tEditField = uieditfield(app.UIFigure, 'numeric');
            app.k_lEditFieldLabel = uilabel(app.UIFigure);
            app.k_lEditFieldLabel.Text = 'k_l';
            app.k_lEditField = uieditfield(app.UIFigure, 'numeric');
            app.k_dEditFieldLabel = uilabel(app.UIFigure);
            app.k_dEditFieldLabel.Text = 'k_d';
            app.k_dEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frequency_mode_original

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end