classdef Inductance_app_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CreditShadeebHossainLabel   matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        for7TcoilLabel              matlab.ui.control.Label
        InductanceCalculationLabel  matlab.ui.control.Label
        dcmEditField                matlab.ui.control.NumericEditField
        dcmEditFieldLabel           matlab.ui.control.Label
        LnHEditField                matlab.ui.control.NumericEditField
        LnHEditFieldLabel           matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        wcmEditField                matlab.ui.control.NumericEditField
        wcmEditFieldLabel           matlab.ui.control.Label
        HcmEditField                matlab.ui.control.NumericEditField
        HcmEditFieldLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
u_o=4*pi*10e-7;
u_r=1;
w_1=app.wcmEditField.Value;        % Enter width in cm

w=(w_1)/100;  

h_1=app.HcmEditField.Value;         % Enter the height in cm

h=(h_1)/100; 

%r_1= 0.4:0.01:2.0;    % Enter the radius in cm
d_1=app.dcmEditField.Value;  % Since Diameter is 1.27 cm 

r=(d_1)./100;    

a=((u_o.*u_r)./(pi));
a_1=(-(2*(w+h)));
a_1_1=2*(sqrt(h.^2+w.^2));
a_2_1=(h+(sqrt(h^2+w^2)))./w;
a_2=-h*log(a_2_1);
a_3_1=(w+(sqrt(h^2+w^2)))./h;
a_3=-w*log(a_3_1);
a_4_1=(2*h)./(r./2);
a_4=h*log(a_4_1);
a_5_1=(2*w)./(r./2);
a_5=w*(log(a_5_1));
L=a*(a_1+a_2+a_3+a_4+a_5+a_1_1);
L_1=L*1e8;  % The magnitude is in nH
app.LnHEditField.Value=L_1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HcmEditFieldLabel = uilabel(app.UIFigure);
            app.HcmEditFieldLabel.Text = 'H(cm)';
            app.HcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.wcmEditFieldLabel = uilabel(app.UIFigure);
            app.wcmEditFieldLabel.Text = 'w(cm)';
            app.wcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.LnHEditFieldLabel = uilabel(app.UIFigure);
            app.LnHEditFieldLabel.Text = 'L(nH)';
            app.LnHEditField = uieditfield(app.UIFigure, 'numeric');
            app.dcmEditFieldLabel = uilabel(app.UIFigure);
            app.dcmEditFieldLabel.Text = 'd(cm)';
            app.dcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InductanceCalculationLabel = uilabel(app.UIFigure);
            app.InductanceCalculationLabel.Text = 'Inductance Calculation ';
            app.for7TcoilLabel = uilabel(app.UIFigure);
            app.for7TcoilLabel.Text = 'for 7T coil ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'rectangular coil.jpg');
            app.CreditShadeebHossainLabel = uilabel(app.UIFigure);
            app.CreditShadeebHossainLabel.Text = 'Credit: Shadeeb Hossain ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inductance_app_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end